classdef Palisade_app1_2025 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GBmisEditField                  matlab.ui.control.NumericEditField
        GBmisLabel                      matlab.ui.control.Label
        NoofidentifiedJapanTwinsLabel   matlab.ui.control.Label
        ProbLabel                       matlab.ui.control.Label
        ProbabilityEditField            matlab.ui.control.NumericEditField
        IdentifymaximaButton            matlab.ui.control.Button
        CalcprobabilityButton           matlab.ui.control.Button
        NoofMaximaEditField             matlab.ui.control.NumericEditField
        NoofMaximaEditFieldLabel        matlab.ui.control.Label
        AcceptpointsButton              matlab.ui.control.Button
        AreaweightedSwitch              matlab.ui.control.Switch
        AreaweightedSwitchLabel         matlab.ui.control.Label
        CalcallanglesButton             matlab.ui.control.Button
        HalfwidthEditField              matlab.ui.control.NumericEditField
        HalfwidthLabel                  matlab.ui.control.Label
        ExportdataButton                matlab.ui.control.Button
        AngleLabel                      matlab.ui.control.Label
        CompareButton                   matlab.ui.control.Button
        PlotButton                      matlab.ui.control.Button
        OrientationcomparisonLabel      matlab.ui.control.Label
        PlotEBSDdataButton              matlab.ui.control.Button
        OrientationGroup1DropDown       matlab.ui.control.DropDown
        OrientationGroup1DropDownLabel  matlab.ui.control.Label
        LoadEBSDdataButton              matlab.ui.control.Button
        OrientationGroup2DropDown       matlab.ui.control.DropDown
        OrientationGroup2DropDownLabel  matlab.ui.control.Label
        UIAxes4                         matlab.ui.control.UIAxes
        UIAxes3                         matlab.ui.control.UIAxes
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        EBSDdata % EBSD data to analysis 
        GB % Grains
        GB_Qz % Just Quartz grains 
        EBSDdata_quartz % Isolating out the quartz EBSD data for pole figure analysis 
        EBSDdata_qz_Ori1p % EBSD orientations, one-point-per grain
        EBSDdata_qz_Ori1p_weights % Grain sizes for the weighting 
        h % quartz crystal symmetry [0001]
        m % quartz crystal symmetry {1-100} group of {m} planes
        qtzODF % Pole figure one-point-per grain 
        SF_1pg_0001 % Density function just [c] axis 
        levels % contours for pole figures
        levels_conc % Contours for single plots
        gray1 % First colour scheme 
        gray2 % colour scheme for pole figure 
        No_points % number of peaks needed 
        Vec0001
        Group_1  % First group to compare 
        Group_2 % Second group to compare 
        Value_1
        Value_2
        closeToOri1
        closeToOri2 
        ori1ODF_large
        tx % Label text for c-axis plot 
        Plane_Normal % Plane that contains them both?
        angle_1 % Angle in radians between the two points 
        All_angles % Storing the angle between two 
        angle_temp 
        angle_store 
        Ori_fix
        Ori_change
        fig1 % Creating a variable to prevent things popping up 
        modes_0001
        BothGroups
        Maxima1 
        Maxima2
        JP_Prob
        NotJP_Prob
        No_MaxPair
        probability
        Var1
        Var2

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
                

        end

        % Button pushed function: LoadEBSDdataButton
        function LoadData(app, event)
           
            % Load EBSD data from the main workspace
            app.LoadEBSDdataButton.BackgroundColor = 'Yellow';
            app.LoadEBSDdataButton.Text = 'Working';
            figure('Visible', 'off');

            try
                % Save EBSD data in a variable to be used in app
                app.EBSDdata = evalin('base', 'ebsd');

            catch
                % If no EBSD Data in the workspace state in the command
                % line
                msg = 'No EBSD data in workspace.  Import EBSD into main workspace using import_wizard(''EBSD'') command first';
                errordlg(msg)
                return
            end

            % Calculate grains
            app.GB = calcGrains(app.EBSDdata('indexed'), 'angle', app.GBmisEditField.Value*degree);
            app.GB_Qz = app.GB('Quartz-new');

            % Prepare data for plotting pole figures
            app.EBSDdata_quartz = app.EBSDdata('Quartz-new');
            app.EBSDdata_qz_Ori1p = app.GB('Quartz-new').meanOrientation;
            app.EBSDdata_qz_Ori1p_weights = app.GB('Quartz-new').grainSize;
            app.h = Miller({0,0,0,1},app.EBSDdata('Quartz-new').CS,'direction');
            app.m = Miller({1,-1,0,0},app.EBSDdata('Quartz-new').CS,'plane');
            % TEMP SOLUTION = set levels now. FUTURE: make levels something that changes depending
            % on the data/an input
            app.levels = 1:2:10;
            app.levels_conc = 1:5:50;
            app.gray1 = flipud(colormap('gray'));
            app.gray2 = flipud(colormap('bone'));


            % Check EBSD Data has loaded
            % If sucessfully loaded change button color
            if length(app.EBSDdata) < 10
                msg = 'Load EBSD data first, using import_wizard';
                errordlg(msg)
            else
                app.LoadEBSDdataButton.BackgroundColor = 'Green';
                app.LoadEBSDdataButton.Text = 'Load EBSD data';
            end

            % TEMP SOLUTION: Plotting convention is set. FUTURE: have this
            % modified by user 

            setMTEXpref('xAxisDirection','east');
            setMTEXpref('zAxisDirection','intoPlane');
        end

        % Button pushed function: PlotEBSDdataButton
        function PlotEBSD(app, event)
            % Plot EBSD band contrast map
            % First change button to indicate work in progress
            app.PlotEBSDdataButton.BackgroundColor = 'Yellow';
            app.PlotEBSDdataButton.Text = 'Working';

            % Set the greyscale
            plot(app.EBSDdata, app.EBSDdata.bc, 'parent', app.UIAxes)
            colormap(app.UIAxes,gray)
            hold off

            app.PlotEBSDdataButton.BackgroundColor = 'Green';
            app.PlotEBSDdataButton.Text = 'Plot EBSD data';

        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
           
            % Plot initial c-axis pole figure
            result = 0;
            figure('Visible', 'off');

            switch app.AreaweightedSwitch.Value
                case 'Off'
                    app.qtzODF = calcDensity(app.EBSDdata_qz_Ori1p, 'halfwidth', app.HalfwidthEditField.Value*degree);
                    plotPDF(app.qtzODF, app.h, 'anitpodal', 'lower', 'parent', app.UIAxes2);
                    hold on;
                    plotPDF(app.qtzODF,app.h,'antipodal','lower', 'contour',app.levels,'linewidth',1,'linecolor','k', 'parent', app.UIAxes2);
                    hold on
                    colormap(app.UIAxes2,app.gray1)
                    title(app.UIAxes2,'One point per grain');

                case 'On'
                    app.qtzODF = calcDensity(app.EBSDdata_qz_Ori1p, 'halfwidth', app.HalfwidthEditField.Value*degree, 'weights', app.EBSDdata_qz_Ori1p_weights);
                    plotPDF(app.qtzODF, app.h, 'anitpodal', 'lower', 'parent', app.UIAxes2);
                    hold on;
                    plotPDF(app.qtzODF,app.h,'antipodal','lower', 'contour',app.levels,'linewidth',1,'linecolor','k', 'parent', app.UIAxes2);
                    hold on
                    colormap(app.UIAxes2,app.gray1)
                    title(app.UIAxes2,'One point per grain, area weighted');
            end

        end

        % Value changed function: OrientationGroup2DropDown
        function OrientationGroup2DropDownValueChanged(app, event)
        
            % Select orientation group to compare
            app.Value_2 = app.OrientationGroup2DropDown.Value;

            for t = 1:1:app.No_points;
                if app.Value_2 == ['Ori ', num2str(t)];
                    app.Group_2 = t;
                end
            end
        end

        % Value changed function: OrientationGroup1DropDown
        function OrientationGroup1DropDownValueChanged(app, event)
            
            % Select orientation group to compare
            app.Value_1 = app.OrientationGroup1DropDown.Value;
            for t = 1:1:app.No_points;
                if app.Value_1 == ['Ori ', num2str(t)];
                    app.Group_1 = t;
                end
            end

        end

        % Button pushed function: CompareButton
        function CompareButtonPushed(app, event)

            % Fine grains that correspond to the two maximas
            app.closeToOri1 =  app.GB_Qz(angle(app.Vec0001,app.modes_0001(app.Group_1))/degree < app.GBmisEditField.Value);
            app.closeToOri2 = app.GB_Qz(angle(app.Vec0001, app.modes_0001(app.Group_2))/degree < app.GBmisEditField.Value);
            app.BothGroups = app.GB_Qz(angle(app.Vec0001,app.modes_0001(app.Group_1))/degree < app.GBmisEditField.Value | angle(app.Vec0001,app.modes_0001(app.Group_2))/degree < app.GBmisEditField.Value);


            switch app.AreaweightedSwitch.Value
                case 'Off'
                    app.CompareButton.BackgroundColor = 'Yellow';
                    app.CompareButton.Text = 'Working';
                    app.ori1ODF_large = calcDensity(app.BothGroups.meanOrientation,'halfwidth',app.HalfwidthEditField.Value*degree);


                    plotPDF(app.ori1ODF_large, app.h, 'anitpodal', 'lower', 'parent', app.UIAxes3);
                    hold on;
                    plotPDF(app.ori1ODF_large,app.h,'antipodal','lower', 'contour',app.levels,'linewidth',1,'linecolor','k', 'parent', app.UIAxes3);
                    hold on
                    colormap(app.UIAxes3,app.gray1)

                    plotPDF(app.closeToOri1.meanOrientation, app.h, 'markersize', 4, 'MarkerFaceColor',[118/255 42/255 131/255],...
                        'MarkerEdgeColor','k', 'anitpodal', 'lower', 'parent', app.UIAxes3);
                    hold on
                    plotPDF(app.closeToOri2.meanOrientation, app.h, 'markersize', 4, 'MarkerFaceColor',  [127/255 191/255 123/255],...
                        'MarkerEdgeColor','k', 'anitpodal', 'lower', 'parent', app.UIAxes3);


                    plotPDF(app.ori1ODF_large, app.m, 'anitpodal', 'lower', 'parent', app.UIAxes4);
                    hold on;
                    plotPDF(app.ori1ODF_large,app.m,'antipodal','lower', 'contour',app.levels,'linewidth',1,'linecolor','k', 'parent', app.UIAxes4);
                    hold on
                    colormap(app.UIAxes4,app.gray1)

                    plotPDF(app.closeToOri1.meanOrientation, app.m, 'markersize', 4, 'MarkerFaceColor',[118/255 42/255 131/255],...
                        'MarkerEdgeColor','k', 'anitpodal', 'lower', 'parent', app.UIAxes4);
                    hold on
                    plotPDF(app.closeToOri2.meanOrientation, app.m, 'markersize', 4, 'MarkerFaceColor',  [127/255 191/255 123/255],...
                        'MarkerEdgeColor','k', 'anitpodal', 'lower', 'parent', app.UIAxes4);


                    app.angle_1 = (angle(app.modes_0001(app.Group_1), app.modes_0001(app.Group_2), 'antipodal'));
                    app.AngleLabel.Text = ['Angle: ', num2str(round((app.angle_1*(180/pi)),0)), char(176)];
                    app.Plane_Normal = (cross(app.modes_0001(app.Group_1),app.modes_0001(app.Group_2)));

                    % Annotate the plane that contains both maxima and the
                    % pole to plane (aka rotation axis)
                    plot(app.Plane_Normal, 'plane', 'lineWidth', 3, 'color', 'r',  'parent', app.UIAxes3, 'antipodal')
                    hold on
                    plot(app.Plane_Normal, 'marker', 'o', 'MarkerFaceColor', 'r', ...
                        'MarkerEdgeColor', 'k',  'parent', app.UIAxes3, 'antipodal')
                    hold on

                    app.CompareButton.BackgroundColor =  [0.96 0.96 0.96];
                    app.CompareButton.Text = 'Compare';

                case 'On'
                    app.CompareButton.BackgroundColor = 'Yellow';
                    app.CompareButton.Text = 'Working';

                    app.BothGroups = app.GB_Qz(angle(app.Vec0001, app.modes_0001(app.Group_1))/degree < app.GBmisEditField.Value | angle(app.Vec0001, app.modes_0001(app.Group_2))/degree < app.GBmisEditField.Value);
                    app.ori1ODF_large = calcDensity(app.BothGroups.meanOrientation,'halfwidth',app.HalfwidthEditField.Value*degree, 'weight', app.BothGroups.grainSize);


                    plotPDF(app.ori1ODF_large, app.h, 'anitpodal', 'lower', 'parent', app.UIAxes3);
                    hold on;
                    plotPDF(app.ori1ODF_large,app.h,'antipodal','lower', 'contour',app.levels,'linewidth',1,'linecolor','k', 'parent', app.UIAxes3);
                    hold on
                    colormap(app.UIAxes3,app.gray1)

                    plotPDF(app.closeToOri1.meanOrientation, app.h, 'markersize', 4, 'MarkerFaceColor',[118/255 42/255 131/255],...
                        'MarkerEdgeColor','k', 'anitpodal', 'lower', 'parent', app.UIAxes3);
                    hold on
                    plotPDF(app.closeToOri2.meanOrientation, app.h, 'markersize', 4, 'MarkerFaceColor',  [127/255 191/255 123/255],...
                        'MarkerEdgeColor','k', 'anitpodal', 'lower', 'parent', app.UIAxes3);

                    app.angle_1 = (angle(app.modes_0001(app.Group_1), app.modes_0001(app.Group_2), 'antipodal'));
                    app.AngleLabel.Text = ['Angle: ', num2str(round((app.angle_1*(180/pi)),0)), char(176)];
                    app.Plane_Normal = (cross(app.modes_0001(app.Group_1),app.modes_0001(app.Group_2)));


                    % Annotate the plane that contains both maxima and the
                    % pole to plane (aka rotation axis)
                    plot(app.Plane_Normal, 'plane', 'lineWidth', 3, 'color', 'r',  'parent', app.UIAxes3, 'antipodal')
                    hold on
                    plot(app.Plane_Normal, 'marker', 'o', 'MarkerFaceColor', 'r', ...
                        'MarkerEdgeColor', 'k',  'parent', app.UIAxes3, 'antipodal')
                    hold on


                    plotPDF(app.ori1ODF_large, app.m, 'anitpodal', 'lower', 'parent', app.UIAxes4);
                    hold on;
                    plotPDF(app.ori1ODF_large,app.m,'antipodal','lower', 'contour',app.levels,'linewidth',1,'linecolor','k', 'parent', app.UIAxes4);
                    hold on
                    colormap(app.UIAxes4,app.gray1)

                    plotPDF(app.closeToOri1.meanOrientation, app.m, 'markersize', 4, 'MarkerFaceColor',[118/255 42/255 131/255],...
                        'MarkerEdgeColor','k', 'anitpodal', 'lower', 'parent', app.UIAxes4);
                    hold on
                    plotPDF(app.closeToOri2.meanOrientation, app.m, 'markersize', 4, 'MarkerFaceColor',  [127/255 191/255 123/255],...
                        'MarkerEdgeColor','k', 'anitpodal', 'lower', 'parent', app.UIAxes4);


                    app.CompareButton.BackgroundColor =  [0.96 0.96 0.96];
                    app.CompareButton.Text = 'Compare';


            end

            % TEMP SOLUTION
            % AIM: To get the grains to plot on the original BC map
            % Errors out so for the time being create new figure
            figure
            plot(app.EBSDdata, app.EBSDdata.bc, 'parent', app.UIAxes)
            hold on
            colormap(app.UIAxes,gray)
            hold on
            plot(app.closeToOri1,'FaceColor', [118/255 42/255 131/255])
            hold on
            plot(app.closeToOri2,'FaceColor', [127/255 191/255 123/255])
            hold on
            plot(app.GB.boundary,'LineWidth',0.1,'LineColor','k')
            hold on


        end

        % Button pushed function: CalcallanglesButton
        function CalcallanglesButtonPushed(app, event)

            % Calculate angles between all maxima combinations
            app.All_angles = [];
            app.Maxima1 = [];
            app.Maxima2 = [];


            for a = 1:1:(app.No_points-1)
                app.Ori_fix = app.modes_0001(a);
                for b = (a+1):1:app.No_points
                    app.Maxima1 = [app.Maxima1; a];
                    app.Maxima2 = [app.Maxima2; b];
                    app.angle_temp = (angle(app.Ori_fix, app.modes_0001(b), 'antipodal'));
                    app.angle_store = round(app.angle_temp*(180/pi));
                    app.All_angles = [app.All_angles; app.angle_store];
                end
            end


            % Print table in the command window
            T = table(app.Maxima1, app.Maxima2, app.All_angles, 'VariableNames', {'First Modal group' 'Second Modal group' 'Angle'})

            app.CalcallanglesButton.BackgroundColor = 'Green';
            app.CalcallanglesButton.Text = 'Check cmd wnd';
 
        end

        % Button pushed function: ExportdataButton
        function ExportData(app, event)
           
         
            ModalGroup_1 = app.closeToOri1;
            ModalGroup_2 = app.closeToOri2;
            BothGroups = app.BothGroups;

            uisave({'ModalGroup_1', 'ModalGroup_2', 'BothGroups'})


        end

        % Button pushed function: AcceptpointsButton
        function AcceptpointsButtonPushed(app, event)

            for a = 1:1:app.NoofMaximaEditField.Value

                % Populate drop downs based on the number of maximas
                app.tx = ['Ori ',num2str(a)];
                if a ~= 1
                    app.OrientationGroup1DropDown.Items(end + 1) = {app.tx};
                    app.OrientationGroup2DropDown.Items(end + 1) = {app.tx};
                end

            end

            app.AcceptpointsButton.BackgroundColor = 'Green';


        end

        % Button pushed function: IdentifymaximaButton
        function PushIdentifymaximaButton(app, event)
            % TEMP SOLUTION: Have new figures plotting up for each of the
            % attempts to characterise maximas
            % FUTURE: Try to overwrite the same figure, begins of code
            % below.
            %  if exist('f') == 1
            %     close(f)
            %
            % elseif exist('f') == 0
            %     f=figure;
            %     hold on
            % end


            app.IdentifymaximaButton.BackgroundColor = 'Yellow';
            app.IdentifymaximaButton.Text = 'Working';

            % Plot C-axis figure that can be annotated
            % qtzODF should have the area-weighted component factored in
            % already
            figure
            plotPDF(app.qtzODF, app.h, ...
                'anitpodal', 'lower');
            hold on;
            plotPDF(app.qtzODF,app.h,...
                'antipodal','lower', 'contour',...
                app.levels,'linewidth',1,...
                'linecolor','k');
            hold on
            colormap(app.gray1)

            % Vector of just the [c]-axis
            app.Vec0001 = app.EBSDdata_qz_Ori1p * app.h;
            switch app.AreaweightedSwitch.Value
                case 'Off'
                    app.SF_1pg_0001 = calcDensity(app.Vec0001,'halfwidth',app.HalfwidthEditField.Value*degree);
                    [~,app.modes_0001] = max(app.SF_1pg_0001,'numLocal',app.NoofMaximaEditField.Value);


                    for a = 1:1:app.NoofMaximaEditField.Value
                        annotate(app.modes_0001(a),'antipodal','label',num2str(a),'backgroundcolor','w');

                    end
                case 'On'
                    app.SF_1pg_0001 = calcDensity(app.Vec0001,'halfwidth',app.HalfwidthEditField.Value*degree, 'Weights', app.EBSDdata_qz_Ori1p_weights);
                    [~,app.modes_0001] = max(app.SF_1pg_0001,'numLocal',app.NoofMaximaEditField.Value);

                    for a = 1:1:app.NoofMaximaEditField.Value
                        %hold(app.UIAxes2); <- delete?
                        annotate(app.modes_0001(a),'antipodal','label',num2str(a),'backgroundcolor','w');
                    end
            end

            app.No_points = app.NoofMaximaEditField.Value;
            app.IdentifymaximaButton.BackgroundColor = 'white';
            app.IdentifymaximaButton.Text = 'Calculate';

        end

        % Button pushed function: CalcprobabilityButton
        function CalcprobabilityButtonPushed(app, event)
        
            app.No_MaxPair = length(app.All_angles);
            app.JP_Prob = 0.03; % 3% Probability that any two neighbours will be a Japan Twin by chance
            app.NotJP_Prob = 1-app.JP_Prob; % Probability that you don't get a Japan Twin

            % Calulate the probability that what you are seeing is random
            app.probability = (factorial(app.No_MaxPair)/(factorial(app.ProbabilityEditField.Value)*(factorial(app.No_MaxPair-app.ProbabilityEditField.Value))))*((app.JP_Prob)^app.ProbabilityEditField.Value)*((app.NotJP_Prob)^(app.No_MaxPair-app.ProbabilityEditField.Value));
            disp(app.probability)

            % Display angle on the GUI to be seen
            if app.probability >= 0.01
                app.ProbLabel.Text = ['P: ', num2str(round((app.probability),2))];
            elseif app.probability<0.01
                app.ProbLabel.Text = 'P: < 0.01';

            end
        

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Band Contrast')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, '[0001]')
            app.UIAxes2.XTick = [];
            app.UIAxes2.XTickLabel = '';
            app.UIAxes2.YTick = [];
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, '[0001] - Subset ')
            app.UIAxes3.XAxisLocation = 'origin';
            app.UIAxes3.XTick = [];
            app.UIAxes3.XTickLabel = {};
            app.UIAxes3.YTick = [];
            app.UIAxes3.YTickLabel = {};
            app.UIAxes4 = uiaxes(app.UIFigure);
            title(app.UIAxes4, '[1-100] - Subset ')
            app.UIAxes4.XTick = [];
            app.UIAxes4.XTickLabel = '';
            app.UIAxes4.YTick = [];
            app.UIAxes4.YTickLabel = '';
            app.OrientationGroup2DropDownLabel = uilabel(app.UIFigure);
            app.OrientationGroup2DropDownLabel.Text = 'Orientation Group 2';
            app.OrientationGroup2DropDown = uidropdown(app.UIFigure);
            app.OrientationGroup2DropDown.Items = {'Ori 1'};
            app.OrientationGroup2DropDown.ValueChangedFcn = createCallbackFcn(app, @OrientationGroup2DropDownValueChanged, true);
            app.OrientationGroup2DropDown.Value = 'Ori 1';
            app.LoadEBSDdataButton = uibutton(app.UIFigure, 'push');
            app.LoadEBSDdataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadData, true);
            app.LoadEBSDdataButton.Text = 'Load EBSD data';
            app.OrientationGroup1DropDownLabel = uilabel(app.UIFigure);
            app.OrientationGroup1DropDownLabel.Text = 'Orientation Group 1';
            app.OrientationGroup1DropDown = uidropdown(app.UIFigure);
            app.OrientationGroup1DropDown.Items = {'Ori 1'};
            app.OrientationGroup1DropDown.ValueChangedFcn = createCallbackFcn(app, @OrientationGroup1DropDownValueChanged, true);
            app.OrientationGroup1DropDown.Value = 'Ori 1';
            app.PlotEBSDdataButton = uibutton(app.UIFigure, 'push');
            app.PlotEBSDdataButton.ButtonPushedFcn = createCallbackFcn(app, @PlotEBSD, true);
            app.PlotEBSDdataButton.Text = 'Plot EBSD data';
            app.OrientationcomparisonLabel = uilabel(app.UIFigure);
            app.OrientationcomparisonLabel.Text = 'Orientation comparison';
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.CompareButton = uibutton(app.UIFigure, 'push');
            app.CompareButton.ButtonPushedFcn = createCallbackFcn(app, @CompareButtonPushed, true);
            app.CompareButton.Tooltip = {'Current bug: If comparing ''Ori-1'', that is the default option, to any other orientation on the first instance of using ''Compare'' you need to first change ''Ori-1'' to another value from the dropdown and then change it back to ''Ori-1''. For following comparisions this is not needed. '};
            app.CompareButton.Text = 'Compare';
            app.AngleLabel = uilabel(app.UIFigure);
            app.AngleLabel.Text = 'Angle:';
            app.ExportdataButton = uibutton(app.UIFigure, 'push');
            app.ExportdataButton.ButtonPushedFcn = createCallbackFcn(app, @ExportData, true);
            app.ExportdataButton.Text = 'Export data';
            app.HalfwidthLabel = uilabel(app.UIFigure);
            app.HalfwidthLabel.Text = 'Half width (°)';
            app.HalfwidthEditField = uieditfield(app.UIFigure, 'numeric');
            app.HalfwidthEditField.Limits = [0 Inf];
            app.HalfwidthEditField.Value = 7.5;
            app.CalcallanglesButton = uibutton(app.UIFigure, 'push');
            app.CalcallanglesButton.ButtonPushedFcn = createCallbackFcn(app, @CalcallanglesButtonPushed, true);
            app.CalcallanglesButton.Tooltip = {'Calculate the angles between all combinations of maxima pairs '};
            app.CalcallanglesButton.Text = 'Calc. all angles';
            app.AreaweightedSwitchLabel = uilabel(app.UIFigure);
            app.AreaweightedSwitchLabel.Text = 'Area weighted ';
            app.AreaweightedSwitch = uiswitch(app.UIFigure, 'slider');
            app.AcceptpointsButton = uibutton(app.UIFigure, 'push');
            app.AcceptpointsButton.ButtonPushedFcn = createCallbackFcn(app, @AcceptpointsButtonPushed, true);
            app.AcceptpointsButton.Text = 'Accept points';
            app.NoofMaximaEditFieldLabel = uilabel(app.UIFigure);
            app.NoofMaximaEditFieldLabel.Text = 'No. of Maxima';
            app.NoofMaximaEditField = uieditfield(app.UIFigure, 'numeric');
            app.NoofMaximaEditField.Limits = [0 Inf];
            app.NoofMaximaEditField.RoundFractionalValues = 'on';
            app.CalcprobabilityButton = uibutton(app.UIFigure, 'push');
            app.CalcprobabilityButton.ButtonPushedFcn = createCallbackFcn(app, @CalcprobabilityButtonPushed, true);
            app.CalcprobabilityButton.Tooltip = {'Must have pressed "Calulate all angles" button prior to calulating the probability'};
            app.CalcprobabilityButton.Text = 'Calc. probability';
            app.IdentifymaximaButton = uibutton(app.UIFigure, 'push');
            app.IdentifymaximaButton.ButtonPushedFcn = createCallbackFcn(app, @PushIdentifymaximaButton, true);
            app.IdentifymaximaButton.Text = 'Identify maxima';
            app.ProbabilityEditField = uieditfield(app.UIFigure, 'numeric');
            app.ProbLabel = uilabel(app.UIFigure);
            app.ProbLabel.Text = '';
            app.NoofidentifiedJapanTwinsLabel = uilabel(app.UIFigure);
            app.NoofidentifiedJapanTwinsLabel.Text = 'No. of identified Japan Twins ';
            app.GBmisLabel = uilabel(app.UIFigure);
            app.GBmisLabel.Text = 'GB mis. (°)';
            app.GBmisEditField = uieditfield(app.UIFigure, 'numeric');
            app.GBmisEditField.Limits = [0 Inf];
            app.GBmisEditField.Value = 15;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Palisade_app1_2025

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end